classdef lab_tong_hop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        TmnghimTab                   matlab.ui.container.Tab
        Tn_KetQuaEditField           matlab.ui.control.EditField
        KtquLabel                    matlab.ui.control.Label
        TimNghiemButton              matlab.ui.control.Button
        Tn_SoLanLapEditField         matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        Tn_KetQuaNghiemEditField     matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel      matlab.ui.control.Label
        Tn_ChonPhuongPhapDropDown    matlab.ui.control.DropDown
        ChnphngphpDropDownLabel      matlab.ui.control.Label
        Tn_NhapSaiSoEditField        matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel        matlab.ui.control.Label
        Tn_KhoangPhanLyEditField     matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        Tn_NhapPhuongTrinhEditField  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        NisuyTab                     matlab.ui.container.Tab
        Ns_DaThucNoiSuyTextArea      matlab.ui.control.TextArea
        KtquathcnisuyLabel           matlab.ui.control.Label
        TinhNoiSuyButton             matlab.ui.control.Button
        Ns_KetQuaEditField           matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel      matlab.ui.control.Label
        Ns_GiaTriXEditField          matlab.ui.control.NumericEditField
        GitrcnnisuyLabel             matlab.ui.control.Label
        Ns_PhuongPhapDropDown        matlab.ui.control.DropDown
        ChnphngphpnisuyLabel         matlab.ui.control.Label
        Ns_DuLieuYEditField          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3     matlab.ui.control.Label
        Ns_DuLieuXEditField          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3     matlab.ui.control.Label
        Ns_UIAxes                    matlab.ui.control.UIAxes
        Tab3                         matlab.ui.container.Tab
        Button2                      matlab.ui.control.Button
        ohmTab                       matlab.ui.container.Tab
        KetQuaEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        TinhDaoHamButton             matlab.ui.control.Button
        HocLabel                     matlab.ui.control.Label
        GiaTriXEditField             matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        PhuongPhapDropDown           matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel   matlab.ui.control.Label
        SaiSoDropDown                matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel     matlab.ui.control.Label
        BuocHEditField               matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel         matlab.ui.control.Label
        HamSoEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel         matlab.ui.control.Label
        DuLieuYEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel       matlab.ui.control.Label
        DuLieuXEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel       matlab.ui.control.Label
        TchphnTab                    matlab.ui.container.Tab
        Tp_NhapNEditField            matlab.ui.control.NumericEditField
        NhpNEditFieldLabel           matlab.ui.control.Label
        TinhTichPhanButton           matlab.ui.control.Button
        Tp_CanEditField              matlab.ui.control.EditField
        GiastLabel                   matlab.ui.control.Label
        Tp_KetQuaEditField           matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2         matlab.ui.control.Label
        Tp_PhuongPhapDropDown        matlab.ui.control.DropDown
        ChnphngphptchphnLabel        matlab.ui.control.Label
        Tp_HamSoEditField            matlab.ui.control.EditField
        NhphmsEditFieldLabel_2       matlab.ui.control.Label
        Tp_DuLieuYEditField          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2     matlab.ui.control.Label
        Tp_DuLieuXEditField          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2     matlab.ui.control.Label
        HocLabel_2                   matlab.ui.control.Label
        Tab6                         matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            disp('Nut tab2');
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            f = @(x) x.^3.*sin(x) + x.*cos(x);
            N = 50;
            a = 0;
            b = 1;
            result = tichphanSimpson(f, a, b, N);
            disp(result);
        end

        % Button pushed function: TinhDaoHamButton
        function TinhDaoHamButtonPushed(app, event)
            f = app.HamSoEditField.Value;
            if ~isempty(f)
                f = str2func(['@(x)' f]);
            end
            h = app.BuocHEditField.Value;
            x = convertStringToNumArray(app.DuLieuXEditField.Value);
            y =convertStringToNumArray(app.DuLieuYEditField.Value);
            saiso = str2double(app.SaiSoDropDown.Value);
            phuongphap = app.PhuongPhapDropDown.Value;
            x0 = app.GiaTriXEditField.Value;
            result = tinhdaoham(f, x,y,x0,h,phuongphap,saiso);
            app.KetQuaEditField.Value = result;
        end

        % Button pushed function: TinhTichPhanButton
        function TinhTichPhanButtonPushed(app, event)
            f = app.Tp_HamSoEditField.Value;
            if ~isempty(f)
                f = str2func(['@(x)' f]);
            end
            xa = convertStringToNumArray(app.Tp_DuLieuXEditField.Value);
            ya =convertStringToNumArray(app.Tp_DuLieuYEditField.Value);
            can_array = convertStringToNumArray(app.Tp_CanEditField.Value);
            disp(xa);
            disp(ya);
            a = can_array(1);
            b = can_array(2);
            N = app.Tp_NhapNEditField.Value;
            method = app.Tp_PhuongPhapDropDown.Value;
            result = tinhtichphan(f, a, b, xa, ya, N, method);
            disp(result)
            app.Tp_KetQuaEditField.Value = result;
        end

        % Button pushed function: TinhNoiSuyButton
        function TinhNoiSuyButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            xa = convertStringToNumArray(app.Ns_DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Ns_DuLieuYEditField.Value);
            x0 = app.Ns_GiaTriXEditField.Value;
            method = app.Ns_PhuongPhapDropDown.Value;

            % Tính đa thức nội suy
            f = tinhnoisuy(xa, ya, method);
            app.Ns_DaThucNoiSuyTextArea.Value = char(f);
            % Tính giá trị nội suy tại giá trị x0
            f_hd = matlabFunction(f);
            f_x0 = f_hd(x0);
            app.Ns_KetQuaEditField.Value = f_x0;

            % Vẽ đồ thị
            x_plt = linspace(min(xa), max(xa), 1000);
            y_plt = f_hd(x_plt);
            cla((app.Ns_UIAxes));
            plot(app.Ns_UIAxes, x_plt, y_plt);
        end

        % Button pushed function: TimNghiemButton
        function TimNghiemButtonPushed(app, event)
            f = app.Tn_NhapPhuongTrinhEditField.Value;
            f = str2func(['@(x)' f]);
            saiso = app.Tn_NhapSaiSoEditField.Value;
            khoangphanly = convertStringToNumArray(app.Tn_KhoangPhanLyEditField.Value);
            a = khoangphanly(1);
            b = khoangphanly(2);
            method = app.Tn_ChonPhuongPhapDropDown.Value;
            
            [nghiem, solanlap] = timnghiem(f, a, b, saiso, method);
%             app.Tn_KetQuaNghiemEditField.Value = nghiem;
            app.Tn_KetQuaEditField.Value = num2str(nghiem);
            app.Tn_SoLanLapEditField.Value = solanlap;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TmnghimTab = uitab(app.TabGroup);
            app.TmnghimTab.Title = 'Tìm nghiệm';
            app.UIAxes = uiaxes(app.TmnghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Tn_NhapPhuongTrinhEditField = uieditfield(app.TmnghimTab, 'text');
            app.Tn_NhapPhuongTrinhEditField.Value = 'x^2 -4';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.Tn_KhoangPhanLyEditField = uieditfield(app.TmnghimTab, 'text');
            app.Tn_KhoangPhanLyEditField.Value = '1.5, 2.5';
            app.NhpsaisEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.Tn_NhapSaiSoEditField = uieditfield(app.TmnghimTab, 'numeric');
            app.Tn_NhapSaiSoEditField.Value = 0.001;
            app.ChnphngphpDropDownLabel = uilabel(app.TmnghimTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.Tn_ChonPhuongPhapDropDown = uidropdown(app.TmnghimTab);
            app.Tn_ChonPhuongPhapDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.Tn_ChonPhuongPhapDropDown.ItemsData = {'chiadoi', 'lap', 'tieptuyen'};
            app.Tn_ChonPhuongPhapDropDown.Value = 'chiadoi';
            app.KtqunghimEditFieldLabel = uilabel(app.TmnghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.Tn_KetQuaNghiemEditField = uieditfield(app.TmnghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.TmnghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Tn_SoLanLapEditField = uieditfield(app.TmnghimTab, 'numeric');
            app.TimNghiemButton = uibutton(app.TmnghimTab, 'push');
            app.TimNghiemButton.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemButtonPushed, true);
            app.TimNghiemButton.Text = 'Tính toán';
            app.KtquLabel = uilabel(app.TmnghimTab);
            app.KtquLabel.Text = 'Kết quả';
            app.Tn_KetQuaEditField = uieditfield(app.TmnghimTab, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.Ns_UIAxes = uiaxes(app.NisuyTab);
            title(app.Ns_UIAxes, 'Title')
            xlabel(app.Ns_UIAxes, 'X')
            ylabel(app.Ns_UIAxes, 'Y')
            zlabel(app.Ns_UIAxes, 'Z')
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.Ns_DuLieuXEditField = uieditfield(app.NisuyTab, 'text');
            app.Ns_DuLieuXEditField.Value = '0.1, 0.2, 0.3, 0.4';
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.Ns_DuLieuYEditField = uieditfield(app.NisuyTab, 'text');
            app.Ns_DuLieuYEditField.Value = '0.09983, 0.19867, 0.29552, 0.38942';
            app.ChnphngphpnisuyLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy';
            app.Ns_PhuongPhapDropDown = uidropdown(app.NisuyTab);
            app.Ns_PhuongPhapDropDown.Items = {'Newton TIến', 'Newton Lùi', 'Lagrange'};
            app.Ns_PhuongPhapDropDown.ItemsData = {'newton_tien', 'newton_lui', 'lagrange'};
            app.Ns_PhuongPhapDropDown.Value = 'newton_tien';
            app.GitrcnnisuyLabel = uilabel(app.NisuyTab);
            app.GitrcnnisuyLabel.Text = 'Giá trị cần nội suy';
            app.Ns_GiaTriXEditField = uieditfield(app.NisuyTab, 'numeric');
            app.Ns_GiaTriXEditField.Value = 0.14;
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.Ns_KetQuaEditField = uieditfield(app.NisuyTab, 'numeric');
            app.TinhNoiSuyButton = uibutton(app.NisuyTab, 'push');
            app.TinhNoiSuyButton.ButtonPushedFcn = createCallbackFcn(app, @TinhNoiSuyButtonPushed, true);
            app.TinhNoiSuyButton.Text = 'Tính toán';
            app.KtquathcnisuyLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyLabel.Text = 'Đa thức nội suy';
            app.Ns_DaThucNoiSuyTextArea = uitextarea(app.NisuyTab);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Button2 = uibutton(app.Tab3, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.DuLieuXEditField = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DuLieuYEditField = uieditfield(app.ohmTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.HamSoEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.BuocHEditField = uieditfield(app.ohmTab, 'numeric');
            app.BuocHEditField.Value = 0.1;
            app.ChngitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaiSoDropDown = uidropdown(app.ohmTab);
            app.SaiSoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaiSoDropDown.ItemsData = {'1', '2'};
            app.SaiSoDropDown.Value = '1';
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.PhuongPhapDropDown = uidropdown(app.ohmTab);
            app.PhuongPhapDropDown.Items = {'Xấp xỉ Tiến', 'Xấp xỉ Lùi', 'Xấp xỉ Trung tâm'};
            app.PhuongPhapDropDown.ItemsData = {'tien', 'lui', 'trungtam'};
            app.PhuongPhapDropDown.Value = 'tien';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.GiaTriXEditField = uieditfield(app.ohmTab, 'numeric');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.TinhDaoHamButton = uibutton(app.ohmTab, 'push');
            app.TinhDaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.TinhDaoHamButton.Text = 'Tính toán';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQuaEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.Tp_DuLieuXEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_DuLieuXEditField.Value = '0.1, 0.2, 0.3, 0.4';
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.Tp_DuLieuYEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_DuLieuYEditField.Value = '0.09983, 0.19867, 0.29552, 0.38942';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Tp_HamSoEditField = uieditfield(app.TchphnTab, 'text');
            app.ChnphngphptchphnLabel = uilabel(app.TchphnTab);
            app.ChnphngphptchphnLabel.Text = 'Chọn phương pháp tích phân';
            app.Tp_PhuongPhapDropDown = uidropdown(app.TchphnTab);
            app.Tp_PhuongPhapDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.Tp_PhuongPhapDropDown.ItemsData = {'hinhthang', 'simpson_13', 'simpson_38'};
            app.Tp_PhuongPhapDropDown.Value = 'hinhthang';
            app.KtquEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.Tp_KetQuaEditField = uieditfield(app.TchphnTab, 'numeric');
            app.GiastLabel = uilabel(app.TchphnTab);
            app.GiastLabel.Text = 'Nhập cận để tính tích phân';
            app.Tp_CanEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_CanEditField.Value = '0.25, 0.35';
            app.TinhTichPhanButton = uibutton(app.TchphnTab, 'push');
            app.TinhTichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @TinhTichPhanButtonPushed, true);
            app.TinhTichPhanButton.Text = 'Tính toán';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.Tp_NhapNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_tong_hop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end